classdef bode_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Hyperlink              matlab.ui.control.Hyperlink
        DropDown_R             matlab.ui.control.DropDown
        DropDown_L             matlab.ui.control.DropDown
        DropDown_C             matlab.ui.control.DropDown
        LEditField             matlab.ui.control.NumericEditField
        LEditFieldLabel        matlab.ui.control.Label
        CEditField             matlab.ui.control.NumericEditField
        CEditFieldLabel        matlab.ui.control.Label
        REditField             matlab.ui.control.NumericEditField
        REditFieldLabel        matlab.ui.control.Label
        SalvaImmagineButton    matlab.ui.control.Button
        CalcolaGraficoButton   matlab.ui.control.Button
        CircuitoDropDown       matlab.ui.control.DropDown
        CircuitoDropDownLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes_Phase           matlab.ui.control.UIAxes
        UIAxes_Magnitude       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcolaGraficoButton
        function CalcolaGraficoButtonPushed(app, event)
            
            % initialize transfer function
            s = tf('s');
            
            % compute transfer function for the circuit
            circuit = app.CircuitoDropDown.Value;
            R = app.REditField.Value * str2double(app.DropDown_R.Value);
            C = app.CEditField.Value * str2double(app.DropDown_C.Value);
            L = app.LEditField.Value * str2double(app.DropDown_L.Value);
            switch circuit
                case 'RC'
                    H = 1/(1+s*R*C);
                case 'CR'
                    H = s*R*C/(1+s*R*C);
                case 'RL'
                    H = (s*L/R)/(1+s*L/R);
                case 'LR'
                    H = 1/(1+s*L/R);
            end

            % compute mag and phase of the transfer function
            [mag, phase, w_out] = bode(H);
            mag = squeeze(mag);
            phase = squeeze(phase);

            % switch mag to decibel
            mag = 20*log10(mag);

            % plot mag results
            semilogx(app.UIAxes_Magnitude, w_out, mag, 'linew', 1.2)
            xlim(app.UIAxes_Magnitude, 'auto')
            ylim(app.UIAxes_Magnitude, [-inf, 10])

            % plot phase result
            semilogx(app.UIAxes_Phase, w_out, phase, 'linew', 1.2)
            xlim(app.UIAxes_Phase, 'auto')
            ylim(app.UIAxes_Phase, [min(phase)-10, max(phase)+10])
            yline(app.UIAxes_Phase, -90, 'k:', 'LineWidth', 0.6)
            yline(app.UIAxes_Phase, 0, 'k:', 'LineWidth', 0.6)
            yline(app.UIAxes_Phase, 90, 'k:', 'LineWidth', 0.6)

        end

        % Value changed function: CircuitoDropDown
        function CircuitoDropDownValueChanged(app, event)
            value = app.CircuitoDropDown.Value;
            switch value
                case 'RC'
                    app.CEditField.Enable = true;
                    app.LEditField.Enable = false;
                    app.DropDown_C.Enable = true;
                    app.DropDown_L.Enable = false;
                    app.LEditField.Value = 1;
                    app.DropDown_L.Value = '1e-3';
                case 'CR'
                    app.CEditField.Enable = true;
                    app.LEditField.Enable = false;
                    app.DropDown_C.Enable = true;
                    app.DropDown_L.Enable = false;
                    app.LEditField.Value = 1;
                    app.DropDown_L.Value = '1e-3';
                case 'RL'
                    app.CEditField.Enable = false;
                    app.LEditField.Enable = true;
                    app.DropDown_C.Enable = false;
                    app.DropDown_L.Enable = true;
                    app.CEditField.Value = 1;
                    app.DropDown_C.Value = '1e-6';
                case 'LR'
                    app.CEditField.Enable = false;
                    app.LEditField.Enable = true;
                    app.DropDown_C.Enable = false;
                    app.DropDown_L.Enable = true;
                    app.CEditField.Value = 1;
                    app.DropDown_C.Value = '1e-6';
            end
        end

        % Button pushed function: SalvaImmagineButton
        function SalvaImmagineButtonPushed(app, event)
            fig_mag = figure;
            fig_mag.Visible = 'off';
            copyobj(app.UIAxes_Magnitude, fig_mag);
            exportgraphics(fig_mag, 'Diagramma Modulo.png', 'Resolution', 600) 

            fig_phase = figure;
            fig_phase.Visible = 'off';
            copyobj(app.UIAxes_Phase, fig_phase);
            exportgraphics(fig_phase, 'Diagramma Fase.png', 'Resolution', 600) 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {476, 476};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {236, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CircuitoDropDownLabel = uilabel(app.LeftPanel);
            app.CircuitoDropDownLabel.Text = 'Circuito';
            app.CircuitoDropDown = uidropdown(app.LeftPanel);
            app.CircuitoDropDown.Items = {'RC', 'CR', 'RL', 'LR'};
            app.CircuitoDropDown.ValueChangedFcn = createCallbackFcn(app, @CircuitoDropDownValueChanged, true);
            app.CircuitoDropDown.Value = 'RC';
            app.CalcolaGraficoButton = uibutton(app.LeftPanel, 'push');
            app.CalcolaGraficoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolaGraficoButtonPushed, true);
            app.CalcolaGraficoButton.Text = 'Calcola Grafico';
            app.SalvaImmagineButton = uibutton(app.LeftPanel, 'push');
            app.SalvaImmagineButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaImmagineButtonPushed, true);
            app.SalvaImmagineButton.Text = 'Salva Immagine';
            app.REditFieldLabel = uilabel(app.LeftPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.LeftPanel, 'numeric');
            app.REditField.Limits = [1 10000];
            app.REditField.ValueDisplayFormat = '%5.2f';
            app.REditField.Value = 1000;
            app.CEditFieldLabel = uilabel(app.LeftPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CEditField.Limits = [1 1000];
            app.CEditField.ValueDisplayFormat = '%4.2f';
            app.CEditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.LeftPanel);
            app.LEditFieldLabel.Enable = 'off';
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LEditField.Limits = [1 1000];
            app.LEditField.ValueDisplayFormat = '%4.2f';
            app.LEditField.Enable = 'off';
            app.LEditField.Value = 1;
            app.DropDown_C = uidropdown(app.LeftPanel);
            app.DropDown_C.Items = {'F', 'mF', 'uF', 'nF'};
            app.DropDown_C.ItemsData = {'1', '1e-3', '1e-6', '1e-9'};
            app.DropDown_C.Value = '1e-6';
            app.DropDown_L = uidropdown(app.LeftPanel);
            app.DropDown_L.Items = {'H', 'mH', 'uH'};
            app.DropDown_L.ItemsData = {'1', '1e-3', '1e-6'};
            app.DropDown_L.Enable = 'off';
            app.DropDown_L.Value = '1e-3';
            app.DropDown_R = uidropdown(app.LeftPanel);
            app.DropDown_R.Items = {'Ohm', 'kOhm', 'MOhm'};
            app.DropDown_R.ItemsData = {'1', '1e3', '1e6'};
            app.DropDown_R.Value = '1';
            app.Hyperlink = uihyperlink(app.LeftPanel);
            app.Hyperlink.URL = 'https://github.com/gbattocletti/Bode-Plotter';
            app.Hyperlink.Text = 'Come funziona?';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_Magnitude = uiaxes(app.RightPanel);
            title(app.UIAxes_Magnitude, 'Modulo')
            xlabel(app.UIAxes_Magnitude, '\omega')
            ylabel(app.UIAxes_Magnitude, 'Modulo [dB]')
            zlabel(app.UIAxes_Magnitude, 'Z')
            app.UIAxes_Phase = uiaxes(app.RightPanel);
            title(app.UIAxes_Phase, 'Fase')
            xlabel(app.UIAxes_Phase, '\omega')
            ylabel(app.UIAxes_Phase, 'Fase [°]')
            zlabel(app.UIAxes_Phase, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bode_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end